classdef MODB_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        YieldCriteriaUIFigure           matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        YieldCriteriaTab                matlab.ui.container.Tab
        PleasefillthebelowblanksLabel   matlab.ui.control.Label
        PrincipalStress1EditFieldLabel  matlab.ui.control.Label
        PrincipalStress1EditField       matlab.ui.control.NumericEditField
        PrincipalStress2EditFieldLabel  matlab.ui.control.Label
        PrincipalStress2EditField       matlab.ui.control.NumericEditField
        PrincipalStress3EditFieldLabel  matlab.ui.control.Label
        PrincipalStress3EditField       matlab.ui.control.NumericEditField
        MaxYieldCriteriaEditField       matlab.ui.control.NumericEditField
        MaxStressYieldCriteriaButton    matlab.ui.control.Button
        YieldStrengthMPaEditFieldLabel  matlab.ui.control.Label
        YieldStrengthMPaEditField       matlab.ui.control.NumericEditField
        EffectiveStressesMPaLabel       matlab.ui.control.Label
        PoissonsRatioEditFieldLabel     matlab.ui.control.Label
        PoissonsRatioEditField          matlab.ui.control.NumericEditField
        MaxStrainYieldCriteriaEditField  matlab.ui.control.NumericEditField
        MaxStrainYieldCriteriaButton    matlab.ui.control.Button
        StrainEnergyDensityCriteriaEditField  matlab.ui.control.NumericEditField
        StrainEnergyDensityCriteriaButton  matlab.ui.control.Button
        MaxShearStressTrescaCriteriaButton  matlab.ui.control.Button
        MaxShearStressTrescaCriteriaEditField  matlab.ui.control.NumericEditField
        VonMisesCriteriaEditField       matlab.ui.control.NumericEditField
        VonMisesCriteriaButton          matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        EffectiveStressMPaLabel         matlab.ui.control.Label
        EffectiveStressMPaEditField     matlab.ui.control.NumericEditField
        SafetyFactorEditField           matlab.ui.control.NumericEditField
        SafetyFactorButton              matlab.ui.control.Button
        sigma_xxLabel                   matlab.ui.control.Label
        sigma_xxEditField               matlab.ui.control.NumericEditField
        sigma_xyEditFieldLabel          matlab.ui.control.Label
        sigma_xyEditField               matlab.ui.control.NumericEditField
        sigma_yyEditFieldLabel          matlab.ui.control.Label
        sigma_yyEditField               matlab.ui.control.NumericEditField
        sigma_zzEditFieldLabel          matlab.ui.control.Label
        sigma_zzEditField               matlab.ui.control.NumericEditField
        sigma_yzEditFieldLabel          matlab.ui.control.Label
        sigma_yzEditField               matlab.ui.control.NumericEditField
        sigma_xzEditFieldLabel          matlab.ui.control.Label
        sigma_xzEditField               matlab.ui.control.NumericEditField
        EnterPrincipalStressesorallthe6componentsMPaLabel  matlab.ui.control.Label
        ORLabel                         matlab.ui.control.Label
        SafetyFactorLabel               matlab.ui.control.Label
        MStrainYCSFEditField            matlab.ui.control.NumericEditField
        SFDCSFEditField                 matlab.ui.control.NumericEditField
        MSSCSFEditField                 matlab.ui.control.NumericEditField
        VMCSFEditField                  matlab.ui.control.NumericEditField
        MStressYCSFEditField            matlab.ui.control.NumericEditField
        AllResultsButton                matlab.ui.control.Button
        ToCalculateAlltheAboveValuesLabel  matlab.ui.control.Label
        ToResetAllValuesLabel           matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        VisualizationTab                matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        GraphButtonGroup                matlab.ui.container.ButtonGroup
        MohrscircleButton               matlab.ui.control.Button
        YieldComparisonsButton          matlab.ui.control.Button
        VonMisesButton                  matlab.ui.control.Button
        HelpTab                         matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        IntroductionTab                 matlab.ui.container.Tab
        HTML8                           matlab.ui.control.HTML
        UserGuideTab                    matlab.ui.container.Tab
        Image6                          matlab.ui.control.Image
        Image7                          matlab.ui.control.Image
        Image8                          matlab.ui.control.Image
        Image9                          matlab.ui.control.Image
        Image10                         matlab.ui.control.Image
        HTML9                           matlab.ui.control.HTML
        TheoryManualTab                 matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        YieldingTab                     matlab.ui.container.Tab
        HTML                            matlab.ui.control.HTML
        MaximumPrincipalStressCriterionTab  matlab.ui.container.Tab
        HTML2                           matlab.ui.control.HTML
        Image                           matlab.ui.control.Image
        MaximumPrincipalStrainCriterionTab  matlab.ui.container.Tab
        HTML3                           matlab.ui.control.HTML
        Image2                          matlab.ui.control.Image
        StrainEnergyDensityCriterionTab  matlab.ui.container.Tab
        HTML4                           matlab.ui.control.HTML
        Image3                          matlab.ui.control.Image
        TrescaCriterionTab              matlab.ui.container.Tab
        HTML5                           matlab.ui.control.HTML
        Image4                          matlab.ui.control.Image
        VonMisesCriterionTab            matlab.ui.container.Tab
        HTML6                           matlab.ui.control.HTML
        Image5                          matlab.ui.control.Image
        DataDictionaryTab               matlab.ui.container.Tab
        HTML7                           matlab.ui.control.HTML
        TestCaseManualTab               matlab.ui.container.Tab
        HTML10                          matlab.ui.control.HTML
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MaxStressYieldCriteriaButton
        function MaxStressYieldCriteriaButtonPushed(app, event)
            sigmaxx=app.sigma_xxEditField.Value;
            sigmaxy=app.sigma_xyEditField.Value;
            sigmayy=app.sigma_yyEditField.Value;
            sigmayz=app.sigma_yzEditField.Value;
            sigmazz=app.sigma_zzEditField.Value;
            sigmaxz=app.sigma_xzEditField.Value;
            YieldStrength=app.YieldStrengthMPaEditField.Value;
            a=sigmaxx+sigmayy+sigmazz; %Advanced Mechanics of Solids By Arthur P Boresi & Schmidth 6 Ed
            b=sigmaxx*sigmayy+sigmaxx*sigmazz+sigmayy*sigmazz-sigmaxy^2-sigmayz^2-sigmaxz^2;
            c=det([sigmaxx sigmaxy sigmaxz; sigmaxy sigmayy sigmayz;sigmaxz sigmayz sigmazz]);
            X=roots([1 -a b -c]); % Finds principal stresses from 6 components
            stress1 =X(1);
            stress2=X(2);
            stress3=X(3);
            if (stress1~=0 && app.PrincipalStress1EditField.Value ~=0)||(stress2~=0 && app.PrincipalStress2EditField.Value ~=0)||(stress3~=0 && app.PrincipalStress3EditField.Value ~=0) 
                fig = uifigure;
                uialert(fig,'Please do not fill both entries.','Invalid File');
                
            
            elseif stress1==0 && stress2==0 &&stress3==0
                stress1=app.PrincipalStress1EditField.Value;
                stress2=app.PrincipalStress2EditField.Value;
                stress3=app.PrincipalStress3EditField.Value;
            
                ans1=[0 0 0];
                ans1(1)=abs(stress1);
                ans1(2)=abs(stress2);
                ans1(3)=abs(stress3);
                app.MaxYieldCriteriaEditField.Value=max(ans1);
                app.MStressYCSFEditField.Value=(YieldStrength/max(ans1));
                
            else
                ans1=[0 0 0];
                ans1(1)=abs(stress1);
                ans1(2)=abs(stress2);
                ans1(3)=abs(stress3);
                app.MaxYieldCriteriaEditField.Value=max(ans1);
                app.MStressYCSFEditField.Value=(YieldStrength/max(ans1));
                
            end
        end

        % Button pushed function: MaxStrainYieldCriteriaButton
        function MaxStrainYieldCriteriaButtonPushed(app, event)
            sigmaxx=app.sigma_xxEditField.Value;
            sigmaxy=app.sigma_xyEditField.Value;
            sigmayy=app.sigma_yyEditField.Value;
            sigmayz=app.sigma_yzEditField.Value;
            sigmazz=app.sigma_zzEditField.Value;
            sigmaxz=app.sigma_xzEditField.Value;
            YieldStrength=app.YieldStrengthMPaEditField.Value;%Advanced Mechanics of Solids By Arthur P Boresi & Schmidth 6 Ed
            a=sigmaxx+sigmayy+sigmazz;
            b=sigmaxx*sigmayy+sigmaxx*sigmazz+sigmayy*sigmazz-sigmaxy^2-sigmayz^2-sigmaxz^2;
            c=det([sigmaxx sigmaxy sigmaxz; sigmaxy sigmayy sigmayz;sigmaxz sigmayz sigmazz]);
            X=roots([1 -a b -c]);% Finds principal stresses from 6 components
            stress1 =X(1);
            stress2=X(2);
            stress3=X(3);
            if (stress1~=0 && app.PrincipalStress1EditField.Value ~=0)||(stress2~=0 && app.PrincipalStress2EditField.Value ~=0)||(stress3~=0 && app.PrincipalStress3EditField.Value ~=0) 
                fig = uifigure;
                uialert(fig,'Please do not fill both entries.','Invalid File');
            elseif stress1==0 && stress2==0 &&stress3==0
                stress1=app.PrincipalStress1EditField.Value;
                stress2=app.PrincipalStress2EditField.Value;
                stress3=app.PrincipalStress3EditField.Value;
            
                poissonratio=app.PoissonsRatioEditField.Value;
                ans2=[0 0 0];
                ans2(1)=abs(stress1-poissonratio*stress2-poissonratio*stress3);
                ans2(2)=abs(stress2-poissonratio*stress1-poissonratio*stress3);
                ans2(3)=abs(stress3-poissonratio*stress1-poissonratio*stress2);
                app.MaxStrainYieldCriteriaEditField.Value=max(ans2);
                app.MStrainYCSFEditField.Value=(YieldStrength/max(ans2));
            else
                poissonratio=app.PoissonsRatioEditField.Value;
                ans2=[0 0 0];
                ans2(1)=abs(stress1-poissonratio*stress2-poissonratio*stress3);
                ans2(2)=abs(stress2-poissonratio*stress1-poissonratio*stress3);
                ans2(3)=abs(stress3-poissonratio*stress1-poissonratio*stress2);
                app.MaxStrainYieldCriteriaEditField.Value=max(ans2);
                app.MStrainYCSFEditField.Value=(YieldStrength/max(ans2));
                
            end
        end

        % Button pushed function: StrainEnergyDensityCriteriaButton
        function StrainEnergyDensityCriteriaButtonPushed(app, event)
            sigmaxx=app.sigma_xxEditField.Value;
            sigmaxy=app.sigma_xyEditField.Value;
            sigmayy=app.sigma_yyEditField.Value;
            sigmayz=app.sigma_yzEditField.Value;
            sigmazz=app.sigma_zzEditField.Value;
            sigmaxz=app.sigma_xzEditField.Value;
            YieldStrength=app.YieldStrengthMPaEditField.Value;
            a=sigmaxx+sigmayy+sigmazz; %Advanced Mechanics of Solids By Arthur P Boresi & Schmidth 6 Ed
            b=sigmaxx*sigmayy+sigmaxx*sigmazz+sigmayy*sigmazz-sigmaxy^2-sigmayz^2-sigmaxz^2;
            c=det([sigmaxx sigmaxy sigmaxz; sigmaxy sigmayy sigmayz;sigmaxz sigmayz sigmazz]);
            X=roots([1 -a b -c]); % Finds principal stresses from 6 components
            stress1 =X(1);
            stress2=X(2);
            stress3=X(3);
            if (stress1~=0 && app.PrincipalStress1EditField.Value ~=0)||(stress2~=0 && app.PrincipalStress2EditField.Value ~=0)||(stress3~=0 && app.PrincipalStress3EditField.Value ~=0) 
                fig = uifigure;
                uialert(fig,'Please do not fill both entries.','Invalid File');
            elseif stress1==0 && stress2==0 &&stress3==0
                stress1=app.PrincipalStress1EditField.Value;
                stress2=app.PrincipalStress2EditField.Value;
                stress3=app.PrincipalStress3EditField.Value;
            
                poissonratio=app.PoissonsRatioEditField.Value;
            
                ans3=sqrt(stress1^2 +stress2^2+stress3^2-2*poissonratio*(stress1*stress2+stress2*stress3+stress3*stress1)); %Advanced Mechanics of Solids By Arthur P Boresi & Schmidth 6 Ed
                app.StrainEnergyDensityCriteriaEditField.Value=ans3;
                app.SFDCSFEditField.Value=YieldStrength/ans3;
                
            else
                poissonratio=app.PoissonsRatioEditField.Value;
            
                ans3=sqrt(stress1^2 +stress2^2+stress3^2-2*poissonratio*(stress1*stress2+stress2*stress3+stress3*stress1));
                app.StrainEnergyDensityCriteriaEditField.Value=ans3;
                app.SFDCSFEditField.Value=YieldStrength/ans3;
            end
        end

        % Button pushed function: MaxShearStressTrescaCriteriaButton
        function MaxShearStressTrescaCriteriaButtonPushed(app, event)
            sigmaxx=app.sigma_xxEditField.Value;
            sigmaxy=app.sigma_xyEditField.Value;
            sigmayy=app.sigma_yyEditField.Value;
            sigmayz=app.sigma_yzEditField.Value;
            sigmazz=app.sigma_zzEditField.Value;
            sigmaxz=app.sigma_xzEditField.Value;
            YieldStrength=app.YieldStrengthMPaEditField.Value;
            a=sigmaxx+sigmayy+sigmazz; %Advanced Mechanics of Solids By Arthur P Boresi & Schmidth 6 Ed
            b=sigmaxx*sigmayy+sigmaxx*sigmazz+sigmayy*sigmazz-sigmaxy^2-sigmayz^2-sigmaxz^2;
            c=det([sigmaxx sigmaxy sigmaxz; sigmaxy sigmayy sigmayz;sigmaxz sigmayz sigmazz]);
            X=roots([1 -a b -c]); % Finds principal stresses from 6 components
            stress1 =X(1);
            stress2=X(2);
            stress3=X(3);
            if (stress1~=0 && app.PrincipalStress1EditField.Value ~=0)||(stress2~=0 && app.PrincipalStress2EditField.Value ~=0)||(stress3~=0 && app.PrincipalStress3EditField.Value ~=0) 
                fig = uifigure;
                uialert(fig,'Please do not fill both entries.','Invalid File');
            elseif stress1==0 && stress2==0 &&stress3==0
                stress1=app.PrincipalStress1EditField.Value;
                stress2=app.PrincipalStress2EditField.Value;
                stress3=app.PrincipalStress3EditField.Value;
            
           
                ans4=[0 0 0];
                ans4(1)=abs((stress2-stress3)/2); %Advanced Mechanics of Solids By Arthur P Boresi & Schmidth 6 Ed
                ans4(2)=abs((stress3-stress1)/2);
                ans4(3)=abs((stress1-stress2)/2);
                app.MaxShearStressTrescaCriteriaEditField.Value=max(ans4);
                app.MSSCSFEditField.Value=YieldStrength/max(ans4);
            else
                ans4=[0 0 0];
                ans4(1)=abs((stress2-stress3)/2);
                ans4(2)=abs((stress3-stress1)/2);
                ans4(3)=abs((stress1-stress2)/2);
                app.MaxShearStressTrescaCriteriaEditField.Value=max(ans4);
                app.MSSCSFEditField.Value=YieldStrength/max(ans4);
            end

        end

        % Button pushed function: VonMisesCriteriaButton
        function VonMisesCriteriaButtonPushed(app, event)
            sigmaxx=app.sigma_xxEditField.Value;
            sigmaxy=app.sigma_xyEditField.Value;
            sigmayy=app.sigma_yyEditField.Value;
            sigmayz=app.sigma_yzEditField.Value;
            sigmazz=app.sigma_zzEditField.Value;
            sigmaxz=app.sigma_xzEditField.Value;
            YieldStrength=app.YieldStrengthMPaEditField.Value;
            a=sigmaxx+sigmayy+sigmazz; %Advanced Mechanics of Solids By Arthur P Boresi & Schmidth 6 Ed
            b=sigmaxx*sigmayy+sigmaxx*sigmazz+sigmayy*sigmazz-sigmaxy^2-sigmayz^2-sigmaxz^2;
            c=det([sigmaxx sigmaxy sigmaxz; sigmaxy sigmayy sigmayz;sigmaxz sigmayz sigmazz]);
            X=roots([1 -a b -c]); % Finds principal stresses from 6 components
            stress1 =X(1);
            stress2=X(2);
            stress3=X(3);
            if (stress1~=0 && app.PrincipalStress1EditField.Value ~=0)||(stress2~=0 && app.PrincipalStress2EditField.Value ~=0)||(stress3~=0 && app.PrincipalStress3EditField.Value ~=0) 
                fig = uifigure;
                uialert(fig,'Please do not fill both entries.','Invalid File');
            elseif (stress1==0 && stress2==0 &&stress3==0)
                stress1=app.PrincipalStress1EditField.Value;
                stress2=app.PrincipalStress2EditField.Value;
                stress3=app.PrincipalStress3EditField.Value;
            
                ans5=sqrt(((stress1-stress2)^2 +(stress2-stress3)^2+ (stress3-stress1)^2)/2); %Advanced Mechanics of Solids By Arthur P Boresi & Schmidth 6 Ed
                app.VonMisesCriteriaEditField.Value=ans5;
                app.VMCSFEditField.Value=YieldStrength/ans5;
            else
                ans5=sqrt(((stress1-stress2)^2 +(stress2-stress3)^2+ (stress3-stress1)^2)/2);
                app.VonMisesCriteriaEditField.Value=ans5;
                app.VMCSFEditField.Value=YieldStrength/ans5;
            end
        end

        % Button pushed function: SafetyFactorButton
        function SafetyFactorButtonPushed(app, event)
            yield=app.YieldStrengthMPaEditField.Value;
            effectivestress=app.EffectiveStressMPaEditField.Value;
            ans6=yield/effectivestress;
            app.SafetyFactorEditField.Value=ans6;
        end

        % Button pushed function: AllResultsButton
        function AllResultsButtonPushed(app, event)
            sigmaxx=app.sigma_xxEditField.Value;
            sigmaxy=app.sigma_xyEditField.Value;
            sigmayy=app.sigma_yyEditField.Value;
            sigmayz=app.sigma_yzEditField.Value;
            sigmazz=app.sigma_zzEditField.Value;
            sigmaxz=app.sigma_xzEditField.Value;
            YieldStrength=app.YieldStrengthMPaEditField.Value;
            a=sigmaxx+sigmayy+sigmazz;
            b=sigmaxx*sigmayy+sigmaxx*sigmazz+sigmayy*sigmazz-sigmaxy^2-sigmayz^2-sigmaxz^2;
            c=det([sigmaxx sigmaxy sigmaxz; sigmaxy sigmayy sigmayz;sigmaxz sigmayz sigmazz]);
            X=roots([1 -a b -c]); % Finds principal stresses from 6 components
            stress1 =X(1);
            stress2=X(2);
            stress3=X(3);
            if (stress1~=0 && app.PrincipalStress1EditField.Value ~=0)||(stress2~=0 && app.PrincipalStress2EditField.Value ~=0)||(stress3~=0 && app.PrincipalStress3EditField.Value ~=0) 
                fig = uifigure;
                uialert(fig,'Please do not fill both entries.','Invalid File');
                
            
            elseif stress1==0 && stress2==0 &&stress3==0
                stress1=app.PrincipalStress1EditField.Value;
                stress2=app.PrincipalStress2EditField.Value;
                stress3=app.PrincipalStress3EditField.Value;
            
                ans1=[0 0 0];
                ans1(1)=abs(stress1);
                ans1(2)=abs(stress2);
                ans1(3)=abs(stress3);
                app.MaxYieldCriteriaEditField.Value=max(ans1);
                app.MStressYCSFEditField.Value=(YieldStrength/max(ans1));
                
                poissonratio=app.PoissonsRatioEditField.Value;
                ans2=[0 0 0];
                ans2(1)=abs(stress1-poissonratio*stress2-poissonratio*stress3);
                ans2(2)=abs(stress2-poissonratio*stress1-poissonratio*stress3);
                ans2(3)=abs(stress3-poissonratio*stress1-poissonratio*stress2);
                app.MaxStrainYieldCriteriaEditField.Value=max(ans2);
                app.MStrainYCSFEditField.Value=(YieldStrength/max(ans2));
                
        
                ans3=sqrt(stress1^2 +stress2^2+stress3^2-2*poissonratio*(stress1*stress2+stress2*stress3+stress3*stress1));
                app.StrainEnergyDensityCriteriaEditField.Value=ans3;
                app.SFDCSFEditField.Value=YieldStrength/ans3;
                
                ans4=[0 0 0];
                ans4(1)=abs((stress2-stress3)/2);
                ans4(2)=abs((stress3-stress1)/2);
                ans4(3)=abs((stress1-stress2)/2);
                app.MaxShearStressTrescaCriteriaEditField.Value=max(ans4);
                app.MSSCSFEditField.Value=YieldStrength/max(ans4);
                
                ans5=sqrt(((stress1-stress2)^2 +(stress2-stress3)^2+ (stress3-stress1)^2)/2);
                app.VonMisesCriteriaEditField.Value=ans5;
                app.VMCSFEditField.Value=YieldStrength/ans5;
            else
                ans1=[0 0 0];
                ans1(1)=abs(stress1);
                ans1(2)=abs(stress2);
                ans1(3)=abs(stress3);
                app.MaxYieldCriteriaEditField.Value=max(ans1);
                app.MStressYCSFEditField.Value=(YieldStrength/max(ans1));
                
                poissonratio=app.PoissonsRatioEditField.Value;
                ans2=[0 0 0];
                ans2(1)=abs(stress1-poissonratio*stress2-poissonratio*stress3);
                ans2(2)=abs(stress2-poissonratio*stress1-poissonratio*stress3);
                ans2(3)=abs(stress3-poissonratio*stress1-poissonratio*stress2);
                app.MaxStrainYieldCriteriaEditField.Value=max(ans2);
                app.MStrainYCSFEditField.Value=(YieldStrength/max(ans2));
                
            
                ans3=sqrt(stress1^2 +stress2^2+stress3^2-2*poissonratio*(stress1*stress2+stress2*stress3+stress3*stress1));
                app.StrainEnergyDensityCriteriaEditField.Value=ans3;
                app.SFDCSFEditField.Value=YieldStrength/ans3;
                
                ans4=[0 0 0];
                ans4(1)=abs((stress2-stress3)/2);
                ans4(2)=abs((stress3-stress1)/2);
                ans4(3)=abs((stress1-stress2)/2);
                app.MaxShearStressTrescaCriteriaEditField.Value=max(ans4);
                app.MSSCSFEditField.Value=YieldStrength/max(ans4);
                
                ans5=sqrt(((stress1-stress2)^2 +(stress2-stress3)^2+ (stress3-stress1)^2)/2);
                app.VonMisesCriteriaEditField.Value=ans5;
                app.VMCSFEditField.Value=YieldStrength/ans5;
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.sigma_xxEditField.Value=0;
            app.sigma_xyEditField.Value=0;
            app.sigma_yyEditField.Value=0;
            app.sigma_yzEditField.Value=0;
            app.sigma_zzEditField.Value=0;
            app.sigma_xzEditField.Value=0;
            app.YieldStrengthMPaEditField.Value=0;
            app.PrincipalStress1EditField.Value=0;
            app.PrincipalStress2EditField.Value=0;
            app.PrincipalStress3EditField.Value=0;
            app.EffectiveStressMPaEditField.Value=0;
            app.SafetyFactorEditField.Value=0;
            app.MaxYieldCriteriaEditField.Value=0;
            app.MStressYCSFEditField.Value=0;
            app.MaxStrainYieldCriteriaEditField.Value=0;
            app.MStrainYCSFEditField.Value=0;
            app.StrainEnergyDensityCriteriaEditField.Value=0;
            app.SFDCSFEditField.Value=0;
            app.MaxShearStressTrescaCriteriaEditField.Value=0;
            app.MSSCSFEditField.Value=0;
            app.VonMisesCriteriaEditField.Value=0;
            app.VMCSFEditField.Value=0;
            app.PoissonsRatioEditField.Value=0;
            
        end

        % Button pushed function: YieldComparisonsButton
        function YieldComparisonsButtonPushed(app, event)
 cla(app.UIAxes, 'reset');  
    x = app.MaxYieldCriteriaEditField.Value;
    y = app.MaxStrainYieldCriteriaEditField.Value;
    z = app.StrainEnergyDensityCriteriaEditField.Value;
    k = app.MaxShearStressTrescaCriteriaEditField.Value;
    j = app.VonMisesCriteriaEditField.Value;
    yio = app.YieldStrengthMPaEditField.Value;
    X = [1 2 3 4 5];
    Y = [x y z k j];
    grid(app.UIAxes,'minor')
    plot(app.UIAxes,X,Y,'o-', 'LineWidth', 3.0);
    hold(app.UIAxes, 'on');
   
    ylabel(app.UIAxes, 'Stress (σ)');
    yline(app.UIAxes, yio, '-.', 'YieldStrength'  );
    hold(app.UIAxes, 'on');

   %plot(app.UIAxes, yline(yio))
    xticks(app.UIAxes,[1 2 3 4 5])
    xticklabels(app.UIAxes,{'Maximum Stress YC','Maximum Strain YC','Strain Energy Density YC',... 
    'Tresca YC', 'Von-mises YC'})
    %xtickangle(app.UIAxes, 45);
    
    ylabel(app.UIAxes, 'Stress value σ');
    

        end

        % Button pushed function: MohrscircleButton
        function MohrscircleButtonPushed(app, event)
%                         %Inputs

 cla(app.UIAxes, 'reset');  
 if(app.PrincipalStress1EditField.Value ~=0 || app.PrincipalStress2EditField.Value ~=0 ||app.PrincipalStress3EditField.Value ~=0)
    sigma_x=app.PrincipalStress1EditField.Value;
    tau_xy=0;
    sigma_y=app.PrincipalStress2EditField.Value;
    tau_yz=0;
    sigma_z=app.PrincipalStress3EditField.Value;
    tau_xz=0;

 else
     sigma_x=app.sigma_xxEditField.Value;
     tau_xy=app.sigma_xyEditField.Value;
     sigma_y=app.sigma_yyEditField.Value;
     tau_yz=app.sigma_yzEditField.Value;
     sigma_z=app.sigma_zzEditField.Value;
     tau_xz=app.sigma_xzEditField.Value;
 end
%coefficients of Mohr's circle
a=1;
b=sigma_x+sigma_y+sigma_z;
c=sigma_x*sigma_y+sigma_x*sigma_z+sigma_y*sigma_z...
    -tau_xy^2-tau_yz^2-tau_xz^2;
d=sigma_x*sigma_y*sigma_z+2*tau_xy*tau_yz*tau_xz...
    -sigma_x*tau_yz^2-sigma_y*tau_xz^2-sigma_z*tau_xy^2;
%Find Principle stresses
normal_stresses=roots([a b c d]);
%arrange in descending order
ordered_normal_stresses=sort(normal_stresses,'descend');
sigma_1=ordered_normal_stresses(1);
sigma_2=ordered_normal_stresses(2);
sigma_3=ordered_normal_stresses(3);
tau_1  =(sigma_1-sigma_3)/2;
tau_2  =(sigma_1-sigma_2)/2;
tau_3  =(sigma_2-sigma_3)/2;
%Plotting the 3-D Mohr's Circle
angles=0:0.01:2*pi;
center1=[(sigma_1+sigma_3)/2 0];
center2=[(sigma_1+sigma_2)/2 0];
center3=[(sigma_2+sigma_3)/2 0];
cirlce1=[center1(1)+tau_1*cos(angles') center1(2)+tau_1*sin(angles')];
cirlce2=[center2(1)+tau_2*cos(angles') center2(2)+tau_2*sin(angles')];
cirlce3=[center3(1)+tau_3*cos(angles') center3(2)+tau_3*sin(angles')];
plot(app.UIAxes, cirlce1(:,1),cirlce1(:,2),'b',cirlce2(:,1),cirlce2(:,2),'g',...
    cirlce3(:,1),cirlce3(:,2),'r');grid (app.UIAxes, 'minor');
axis(app.UIAxes, 'equal');
%ANNOTATIONS
if sigma_1>0
    text(app.UIAxes, sigma_1*1.01,0,'\sigma_1','fontsize',15);
else
    text(app.UIAxes, sigma_1*0.95,0,'\sigma_1','fontsize',15)
end
if sigma_2>0
    text(app.UIAxes, sigma_2*1.1,0,'\sigma_2','fontsize',15)
else
    text(app.UIAxes, sigma_2*0.99,0,'\sigma_2','fontsize',15)
end
if sigma_3>0
    text(app.UIAxes, sigma_3*1.1,0,'\sigma_3','fontsize',15);
else
    text(app.UIAxes, sigma_3*0.99,0,'\sigma_3','fontsize',15);
end
text(app.UIAxes, center1(1),tau_1*0.9,'\tau_1','fontsize',15)
text(app.UIAxes, center2(1),tau_2*0.9,'\tau_2','fontsize',15)
text(app.UIAxes, center3(1),tau_3*0.9,'\tau_3','fontsize',15);
xlabel(app.UIAxes, 'Normal Stress, \sigma','fontsize',15);
ylabel(app.UIAxes, 'Shear Stess, \tau','fontsize',15)
        end

        % Button pushed function: VonMisesButton
        function VonMisesButtonPushed(app, event)
 cla(app.UIAxes, 'reset');         
% initialize hydrostatic axis
Imin=-1000;
Imax=1000;
n=100;
I=linspace(Imin,Imax,n);
% deviatoric plane
yieldstress=app.YieldStrengthMPaEditField.Value;

for i=1:n
    theta=(i-1)*2*pi/(n-1);
    y(i)=yieldstress*sqrt(2/3).*cos(theta+45);
    z(i)=yieldstress*sqrt(2/3).*sin(theta+45);
end

plot(app.UIAxes, y, z);
% build 3d plot
x=ones(n,1)*I;
% paramters for rotation
ang=45;
cang=cos(ang*pi/180);
sang=sin(ang*pi/180);
ang2=-45;
cang2=cos(ang2*pi/180);
sang2=sin(ang2*pi/180);

% initialize 3d plot
mises3d=surf(app.UIAxes,[Imin,Imin;Imax,Imax],[Imin,Imin;Imax,Imax],[Imin,Imin;Imax,Imax]);
set(mises3d,'Xdata',(x*cang+z'*ones(1,n)*sang)*cang2+y'*ones(1,n)*sang2,...
            'Ydata',(x*cang+z'*ones(1,n)*sang)*-sang2+y'*ones(1,n)*cang2,...
            'Zdata',(x*-sang+z'*ones(1,n)*cang)*cang2);
ylabel(app.UIAxes, 'σ_y');
zlabel(app.UIAxes, 'σ_z');
xlabel(app.UIAxes, 'σ_x');
grid(app.UIAxes, 'minor');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.YieldCriteriaUIFigure = uifigure('Visible', 'off');
            app.YieldCriteriaUIFigure.Name = 'Yield Criteria';
            app.TabGroup = uitabgroup(app.YieldCriteriaUIFigure);
            app.YieldCriteriaTab = uitab(app.TabGroup);
            app.YieldCriteriaTab.Title = 'Yield Criteria';
            app.PleasefillthebelowblanksLabel = uilabel(app.YieldCriteriaTab);
            app.PleasefillthebelowblanksLabel.Text = 'Please fill the below blanks:';
            app.PrincipalStress1EditFieldLabel = uilabel(app.YieldCriteriaTab);
            app.PrincipalStress1EditFieldLabel.Text = 'Principal Stress 1 ';
            app.PrincipalStress1EditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.PrincipalStress1EditField.ValueDisplayFormat = '%11.9g';
            app.PrincipalStress2EditFieldLabel = uilabel(app.YieldCriteriaTab);
            app.PrincipalStress2EditFieldLabel.Text = 'Principal Stress 2 ';
            app.PrincipalStress2EditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.PrincipalStress2EditField.ValueDisplayFormat = '%11.9g';
            app.PrincipalStress3EditFieldLabel = uilabel(app.YieldCriteriaTab);
            app.PrincipalStress3EditFieldLabel.Text = 'Principal Stress 3 ';
            app.PrincipalStress3EditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.PrincipalStress3EditField.ValueDisplayFormat = '%11.9g';
            app.MaxYieldCriteriaEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.MaxYieldCriteriaEditField.ValueDisplayFormat = '%11.6g';
            app.MaxYieldCriteriaEditField.Editable = 'off';
            app.MaxStressYieldCriteriaButton = uibutton(app.YieldCriteriaTab, 'push');
            app.MaxStressYieldCriteriaButton.ButtonPushedFcn = createCallbackFcn(app, @MaxStressYieldCriteriaButtonPushed, true);
            app.MaxStressYieldCriteriaButton.Text = 'Max Stress Yield Criteria';
            app.YieldStrengthMPaEditFieldLabel = uilabel(app.YieldCriteriaTab);
            app.YieldStrengthMPaEditFieldLabel.Text = 'Yield Strength (MPa)';
            app.YieldStrengthMPaEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.YieldStrengthMPaEditField.ValueDisplayFormat = '%11.9g';
            app.EffectiveStressesMPaLabel = uilabel(app.YieldCriteriaTab);
            app.EffectiveStressesMPaLabel.Text = 'Effective Stresses (MPa):';
            app.PoissonsRatioEditFieldLabel = uilabel(app.YieldCriteriaTab);
            app.PoissonsRatioEditFieldLabel.Text = 'Poisson''s Ratio';
            app.PoissonsRatioEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.PoissonsRatioEditField.Limits = [0 1];
            app.PoissonsRatioEditField.ValueDisplayFormat = '%11.6g';
            app.MaxStrainYieldCriteriaEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.MaxStrainYieldCriteriaEditField.ValueDisplayFormat = '%11.6g';
            app.MaxStrainYieldCriteriaEditField.Editable = 'off';
            app.MaxStrainYieldCriteriaButton = uibutton(app.YieldCriteriaTab, 'push');
            app.MaxStrainYieldCriteriaButton.ButtonPushedFcn = createCallbackFcn(app, @MaxStrainYieldCriteriaButtonPushed, true);
            app.MaxStrainYieldCriteriaButton.Text = 'Max Strain Yield Criteria';
            app.StrainEnergyDensityCriteriaEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.StrainEnergyDensityCriteriaEditField.ValueDisplayFormat = '%11.6g';
            app.StrainEnergyDensityCriteriaEditField.Editable = 'off';
            app.StrainEnergyDensityCriteriaButton = uibutton(app.YieldCriteriaTab, 'push');
            app.StrainEnergyDensityCriteriaButton.ButtonPushedFcn = createCallbackFcn(app, @StrainEnergyDensityCriteriaButtonPushed, true);
            app.StrainEnergyDensityCriteriaButton.Text = 'Strain-Energy Density Criteria';
            app.MaxShearStressTrescaCriteriaButton = uibutton(app.YieldCriteriaTab, 'push');
            app.MaxShearStressTrescaCriteriaButton.ButtonPushedFcn = createCallbackFcn(app, @MaxShearStressTrescaCriteriaButtonPushed, true);
            app.MaxShearStressTrescaCriteriaButton.Text = 'Max Shear Stress(Tresca) Criteria';
            app.MaxShearStressTrescaCriteriaEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.MaxShearStressTrescaCriteriaEditField.ValueDisplayFormat = '%11.6g';
            app.MaxShearStressTrescaCriteriaEditField.Editable = 'off';
            app.VonMisesCriteriaEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.VonMisesCriteriaEditField.ValueDisplayFormat = '%11.6g';
            app.VonMisesCriteriaEditField.Editable = 'off';
            app.VonMisesCriteriaButton = uibutton(app.YieldCriteriaTab, 'push');
            app.VonMisesCriteriaButton.ButtonPushedFcn = createCallbackFcn(app, @VonMisesCriteriaButtonPushed, true);
            app.VonMisesCriteriaButton.Text = 'Von Mises Criteria';
            app.Label = uilabel(app.YieldCriteriaTab);
            app.Label.Text = 'For calculating safety factor, Please fill the effective stress (as per your yield criteria):';
            app.EffectiveStressMPaLabel = uilabel(app.YieldCriteriaTab);
            app.EffectiveStressMPaLabel.Text = 'Effective Stress (MPa)';
            app.EffectiveStressMPaEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.EffectiveStressMPaEditField.ValueDisplayFormat = '%11.9g';
            app.SafetyFactorEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.SafetyFactorEditField.ValueDisplayFormat = '%11.6g';
            app.SafetyFactorEditField.Editable = 'off';
            app.SafetyFactorButton = uibutton(app.YieldCriteriaTab, 'push');
            app.SafetyFactorButton.ButtonPushedFcn = createCallbackFcn(app, @SafetyFactorButtonPushed, true);
            app.SafetyFactorButton.Text = 'Safety Factor';
            app.sigma_xxLabel = uilabel(app.YieldCriteriaTab);
            app.sigma_xxLabel.Text = ' sigma_xx';
            app.sigma_xxEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.sigma_xxEditField.ValueDisplayFormat = '%11.9g';
            app.sigma_xyEditFieldLabel = uilabel(app.YieldCriteriaTab);
            app.sigma_xyEditFieldLabel.Text = 'sigma_xy';
            app.sigma_xyEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.sigma_xyEditField.ValueDisplayFormat = '%11.9g';
            app.sigma_yyEditFieldLabel = uilabel(app.YieldCriteriaTab);
            app.sigma_yyEditFieldLabel.Text = 'sigma_yy';
            app.sigma_yyEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.sigma_yyEditField.ValueDisplayFormat = '%11.9g';
            app.sigma_zzEditFieldLabel = uilabel(app.YieldCriteriaTab);
            app.sigma_zzEditFieldLabel.Text = 'sigma_zz';
            app.sigma_zzEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.sigma_zzEditField.ValueDisplayFormat = '%11.9g';
            app.sigma_yzEditFieldLabel = uilabel(app.YieldCriteriaTab);
            app.sigma_yzEditFieldLabel.Text = 'sigma_yz';
            app.sigma_yzEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.sigma_yzEditField.ValueDisplayFormat = '%11.9g';
            app.sigma_xzEditFieldLabel = uilabel(app.YieldCriteriaTab);
            app.sigma_xzEditFieldLabel.Text = 'sigma_xz';
            app.sigma_xzEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.sigma_xzEditField.ValueDisplayFormat = '%11.9g';
            app.EnterPrincipalStressesorallthe6componentsMPaLabel = uilabel(app.YieldCriteriaTab);
            app.EnterPrincipalStressesorallthe6componentsMPaLabel.Text = 'Enter Principal Stresses or all the 6 components(MPa): ';
            app.ORLabel = uilabel(app.YieldCriteriaTab);
            app.ORLabel.Text = 'OR';
            app.SafetyFactorLabel = uilabel(app.YieldCriteriaTab);
            app.SafetyFactorLabel.Text = 'Safety Factor:';
            app.MStrainYCSFEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.MStrainYCSFEditField.ValueDisplayFormat = '%11.6g';
            app.MStrainYCSFEditField.Editable = 'off';
            app.SFDCSFEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.SFDCSFEditField.ValueDisplayFormat = '%11.6g';
            app.SFDCSFEditField.Editable = 'off';
            app.MSSCSFEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.MSSCSFEditField.ValueDisplayFormat = '%11.6g';
            app.MSSCSFEditField.Editable = 'off';
            app.VMCSFEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.VMCSFEditField.ValueDisplayFormat = '%11.6g';
            app.VMCSFEditField.Editable = 'off';
            app.MStressYCSFEditField = uieditfield(app.YieldCriteriaTab, 'numeric');
            app.MStressYCSFEditField.ValueDisplayFormat = '%11.6g';
            app.MStressYCSFEditField.Editable = 'off';
            app.AllResultsButton = uibutton(app.YieldCriteriaTab, 'push');
            app.AllResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AllResultsButtonPushed, true);
            app.AllResultsButton.Text = 'All Results';
            app.ToCalculateAlltheAboveValuesLabel = uilabel(app.YieldCriteriaTab);
            app.ToCalculateAlltheAboveValuesLabel.Text = 'To Calculate All the Above Values:';
            app.ToResetAllValuesLabel = uilabel(app.YieldCriteriaTab);
            app.ToResetAllValuesLabel.Text = 'To Reset All Values: ';
            app.ResetButton = uibutton(app.YieldCriteriaTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.UIAxes = uiaxes(app.VisualizationTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GraphButtonGroup = uibuttongroup(app.VisualizationTab);
            app.GraphButtonGroup.Title = 'Graph';
            app.MohrscircleButton = uibutton(app.GraphButtonGroup, 'push');
            app.MohrscircleButton.ButtonPushedFcn = createCallbackFcn(app, @MohrscircleButtonPushed, true);
            app.MohrscircleButton.Text = 'Mohr''s circle';
            app.YieldComparisonsButton = uibutton(app.GraphButtonGroup, 'push');
            app.YieldComparisonsButton.ButtonPushedFcn = createCallbackFcn(app, @YieldComparisonsButtonPushed, true);
            app.YieldComparisonsButton.Text = 'Yield Comparisons';
            app.VonMisesButton = uibutton(app.GraphButtonGroup, 'push');
            app.VonMisesButton.ButtonPushedFcn = createCallbackFcn(app, @VonMisesButtonPushed, true);
            app.VonMisesButton.Text = 'Von Mises';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.TabGroup2 = uitabgroup(app.HelpTab);
            app.IntroductionTab = uitab(app.TabGroup2);
            app.IntroductionTab.Title = 'Introduction';
            app.HTML8 = uihtml(app.IntroductionTab);
            app.HTML8.HTMLSource = '<p>Yield Criteria application is used to calculate the effective stress for an isotropic material under multiaxial loading. This application will help in calculating the effective stress for five different yielding criterion - Maximum stress criterion, Maximum strain criterion, Strain energy density criterion, Tresca criterion, and Von Mises criterion. This effective stress can be used to calculate the measure of safety for a material by comparing it with the yield strength. Even the measure of safety factor could be calculated for different criteria through the application. Apart from the calculations, there are visualization which helps to understand the yield criteria. There are plots of stress-strain comparing different criteria, a Mohr circle representation for the given stress field and a 3D yield surface for Von Mises criterion. </p><p>The application would require the user to have a MATLAB compiler of 2019b or higher versions. </p>';
            app.UserGuideTab = uitab(app.TabGroup2);
            app.UserGuideTab.Title = 'User Guide';
            app.Image6 = uiimage(app.UserGuideTab);
            app.Image6.ImageSource = 'Capture - Figure2.JPG';
            app.Image7 = uiimage(app.UserGuideTab);
            app.Image7.ImageSource = 'Capture - Figure3.JPG';
            app.Image8 = uiimage(app.UserGuideTab);
            app.Image8.ImageSource = 'Capture - Figure1.JPG';
            app.Image9 = uiimage(app.UserGuideTab);
            app.Image9.ImageSource = 'Capture - Figure5.JPG';
            app.Image10 = uiimage(app.UserGuideTab);
            app.Image10.ImageSource = 'Capture - Figure4.JPG';
            app.HTML9 = uihtml(app.UserGuideTab);
            app.HTML9.HTMLSource = '<p>Calculating the Effective Stress and the Safety Factor</p><p>The Yield Criteria application helps you calculate the effective stress value for five different yield criteria - Maximum stress yield criterion, Maximum strain yield criterion, Strain energy density criterion, Tresca criterion and Von Mises criterion. Here are the steps to calculate the values.</p><p>Step-1 Open the application &#187 Enter the values of Poisson ratio, Yield strength, Stress values (either principal stress or the 6 stress components)</p><p>An error message would occur if both the principal stress and the 6 stress components are added. (Figure 1)</p><p>Step-2 Click the different criterion buttons to get the value of effective stress and Safety factor.</p><p>To get the values of effective stress for all yield criteria; click on All Results button, and to reset all the values click Reset button. (Figure 2)</p><p>  To get values of safety factor for any other effective stress value, enter the value of stress and click the button of safety factor as shown in figure 3.</p><p>To look at the visuals</p><p>There are plots in the application which helps you to compare different yield criteria, a Mohr circle representation of the stress state and a 3-D plot of the von Mises yield surface. To look at the visuals follow the steps-</p><p>Step 1: Open the application &#187 Enter the values of Poisson ratio, Yield strength, Stress values (either principal stress or the 6 stress components)</p><p>Note: - If both the values are added i.e. principal stress and the 6-stress components then the application will use the 6 stress components values to make the plots.</p><p>Step 2: Click on All results button (gives the value of effective stress and safety factor) – Required for Yield Comparison plot.</p><p>Step 3: Click on the button for which you need the plot – yield comparison, Mohr Circle or Von Mises yield surface. (Figure 4)</p><p>From the help tab get an access to the theory of all the yield criteria. There is a data dictionary which includes the definition of the terminology used in the application as well as the theory. (Figure 5)</p><p>  Theory Manual</p><p>Open application&#187 Help&#187 Theory Manual</p><p>Data Dictionary</p></p>Open application&#187 Help&#187 Data Dictionary</p>';
            app.TheoryManualTab = uitab(app.TabGroup2);
            app.TheoryManualTab.Title = 'Theory Manual';
            app.TabGroup3 = uitabgroup(app.TheoryManualTab);
            app.YieldingTab = uitab(app.TabGroup3);
            app.YieldingTab.Title = 'Yielding';
            app.HTML = uihtml(app.YieldingTab);
            app.HTML.HTMLSource = '<br><p>A yield point is a point on a stress strain curve where the transformation of elastic to plastic behavior occurs for a material. The value of stress associated with yield point is called the yield strength and is denoted by &#963<sub>YS</sub> or simply Y. For a uniaxial loading when stress at a point reaches the yield stress, either there is a failure in a material or there is an elastic to plastic transformation. But generally, we deal with the situation where material is subjected to multiaxial loading and thus, we are to focus on various yield criteria for multiaxial loading.</p><p>The development of these yield criterion is based on the definition of effective uniaxial stress that is the particular combination of the components of a multiaxial stress state. When this effective stress reaches the limiting value, the yielding is initiated in the multiaxial stress state.</p><p>A yield criteria is a descriptive statement that defines the condition under which yielding occurs. It is expressed in mathematical form by means of yield function <i>f</i>(&#963<sub>ij</sub>, Y). Here &#963<sub>ij</sub> is the stress state and Y is the yield strength in the uniaxial loading. The yield function is defined as</p><p><i>f</i>(&#963<sub>ij</sub>, Y) = 0; yielding occurs</p><p><i>f</i>(&#963<sub>ij</sub>, Y) < 0; stress state is elastic</p> <p><i>f</i>(&#963<sub>ij</sub>, Y) > 0; undefined condition</p><p>The yield function is developed by comparing the yield stress Y with the effective stress &#963<sub>e</sub>. The effective stress is a combination of components of multiaxial stress state.</p><p> The following yield criterion are been covered-</p><p>&#8226 Maximum Principal Stress criterion</p> <p>&#8226 Maximum Principal Strain criterion </p><p>&#8226 Strain Energy Density Criterion </p><p>&#8226 Maximum Shear stress or Tresca Criterion</p> <p>&#8226 Distortional Energy Density or Von Mises Criterion</p>';
            app.MaximumPrincipalStressCriterionTab = uitab(app.TabGroup3);
            app.MaximumPrincipalStressCriterionTab.Title = 'Maximum Principal Stress Criterion';
            app.HTML2 = uihtml(app.MaximumPrincipalStressCriterionTab);
            app.HTML2.HTMLSource = '<br><p>Maximum principal stress criterion, also known as Rankine&#8242s criterion, states that yielding begins at a point in the member where the maximum principal stress reaches the value equal to tensile yield stress Y. Let&#8242s say that a single principle stress &#963<sub>1</sub> act at a point on a material, then according to Rankine&#8242s criteria the yielding occurs when &#963<sub>1</sub> reaches the value Y. If there are two principal stress &#963<sub>1</sub> and &#963<sub>2</sub> acting at a point on a material then yielding begins when the maximum of the two reaches the yield strength Y. Thus, the effect of other stress is neglected. For ductile metals, the shear yield stress &#964<sub>Y</sub> is much less than tensile stress Y, so if &#963<sub>1</sub> = &#963<sub>2</sub> = Y at a point than the shear stress is  &#964 = Y, and &#964<sub>Y</sub> &#60 Y, thus the maximum principal stress criteria fails.</p><p>The maximum principal stress criterion is expressed as a yield function as</p><p><i>f</i> = max(|&#963<sub>1</sub>|, |&#963<sub>2</sub>|, |&#963<sub>3</sub>|) - Y</p><p>Thus, we can say that the effective stress is &#963<sub>e</sub> = max(|&#963<sub>1</sub>|, |&#963<sub>2</sub>|, |&#963<sub>3</sub>|). The corresponding yield surface is defined as the locus of the stress state that defines yield criteria (<i>f</i> = 0) and is given as </p><p>&#963<sub>1</sub> = &#177 Y, &#963<sub>2</sub> = &#177 Y, &#963<sub>3</sub> = &#177 Y </p>';
            app.Image = uiimage(app.MaximumPrincipalStressCriterionTab);
            app.Image.ImageSource = 'Capture-max stress.JPG';
            app.MaximumPrincipalStrainCriterionTab = uitab(app.TabGroup3);
            app.MaximumPrincipalStrainCriterionTab.Title = 'Maximum Principal Strain Criterion';
            app.HTML3 = uihtml(app.MaximumPrincipalStrainCriterionTab);
            app.HTML3.HTMLSource = '<br><p>Maximum principal strain criterion, also known as St. Venant&#8242s criterion, states that yielding begins at a point in the member where the maximum principal strain reaches the value equal to tensile yield strain &#949<sub>Y</sub> = Y/E. Thus, yielding at a point in a material under uniaxial loading occurs when &#949<sub>1</sub> = &#949<sub>Y</sub>, i.e. &#963<sub>1</sub> = Y. In biaxial loading condition for an isotropic material, the maximum principal strain is &#949<sub>1</sub> = &#963<sub>1</sub>/E- &#957(&#963<sub>2</sub>/E). If the value of &#963<sub>2</sub> is positive the yielding will occur for &#963<sub>1</sub> &#62 Y and if the value of &#963<sub>2</sub> is positive yielding will occur for &#963<sub>1</sub> &#60 Y. For ductile metals, the prediction of yielding through maximum principal strain criteria isn&#8242t accurate but improves our ability relative to maximum principal stress criteria.</p><p> The maximum principal strain criterion is expressed as a yield function as</p><p> <i>f</i> = max(|&#949<sub>1</sub>|, |&#949<sub>2</sub>|, |&#949<sub>3</sub>|) - &#949<sub>Y</sub></p><p>We will be expressing the yield function in terms of stress. Thus looking at the strain equation.</p><p>&#949<sub>1</sub> = (&#963<sub>1</sub> - &#957&#963<sub>2</sub> - &#957&#963<sub>3</sub>)/E</p><p>Assuming that &#949<sub>1</sub> is the largest principal strain in terms of magnitude, we equate &#949<sub>1</sub> with &#949<sub>Y</sub> to obtain yield function.</p><p><i>f<sub>1</sub></i> = |(&#963<sub>1</sub> - &#957&#963<sub>2</sub> - &#957&#963<sub>3</sub>)/E| - Y/E = 0</p><p><i>f<sub>1</sub></i> = |&#963<sub>1</sub> - &#957&#963<sub>2</sub> - &#957&#963<sub>3</sub>| - Y = 0</p><p>Similarly if &#949<sub>1</sub> or &#949<sub>2</sub> has the largest magnitude </p><p><i>f<sub>2</sub></i> = |&#963<sub>2</sub> - &#957&#963<sub>3</sub> - &#957&#963<sub>1</sub>| - Y = 0</p><p><i>f<sub>3</sub></i> = |&#963<sub>3</sub> - &#957&#963<sub>2</sub> - &#957&#963<sub>1</sub>| - Y = 0</p><p>Hence, the effective stress is defined as </p><p> &#963<sub>e</sub> = max(i&#8800j&#8800k) |&#963<sub>i</sub> - &#957&#963<sub>j</sub> - &#957&#963<sub>k</sub>| </p><p>The yield function is given as <i>f</i> = &#963<sub>e</sub> - Y.</p><p>The yield surface for maximum principal strain criteria is given as </p><P>&#963<sub>1</sub> - &#957&#963<sub>2</sub> - &#957&#963<sub>3</sub> = &#177Y</p><P>&#963<sub>2</sub> - &#957&#963<sub>3</sub> - &#957&#963<sub>1</sub> = &#177Y</p><P>&#963<sub>3</sub> - &#957&#963<sub>2</sub> - &#957&#963<sub>1</sub> = &#177Y</p>';
            app.Image2 = uiimage(app.MaximumPrincipalStrainCriterionTab);
            app.Image2.ImageSource = 'Capture-max strain.JPG';
            app.StrainEnergyDensityCriterionTab = uitab(app.TabGroup3);
            app.StrainEnergyDensityCriterionTab.Title = 'Strain Energy Density Criterion';
            app.HTML4 = uihtml(app.StrainEnergyDensityCriterionTab);
            app.HTML4.HTMLSource = '<br><p>Strain energy density criteria, by Beltrami, states that yielding at a point in a material begins when the strain energy at a point is equal to the strain energy density at yield in uniaxial loading (tension or compression). Given the principal stresses, the strain energy density is given as</p><p>U<sub>o</sub> = [&#963<sub>1</sub><sup>2</sup> + &#963<sub>2</sub><sup>2</sup> + &#963<sub>3</sub><sup>2</sup> - 2&#957(&#963<sub>1</sub>&#963<sub>2</sub> + &#963<sub>2</sub>&#963<sub>3</sub> + &#963<sub>3</sub>&#963<sub>1</sub>)]/2E</p><p>For a uniaxial loading, &#963<sub>1</sub> = Y, &#963<sub>2</sub> = &#963<sub>3</sub> = 0</p><p>U<sub>o Y</sub> = Y<sup>2</sup>/2E</p><p>According to strain energy density criteria, yielding begins when U<sub>o</sub> at a point equals U<sub>o Y</sub>. The yield function is given as</p><p><i>f</i> = [&#963<sub>1</sub><sup>2</sup> + &#963<sub>2</sub><sup>2</sup> + &#963<sub>3</sub><sup>2</sup> - 2&#957(&#963<sub>1</sub>&#963<sub>2</sub> + &#963<sub>2</sub>&#963<sub>3</sub> + &#963<sub>3</sub>&#963<sub>1</sub>)]/2E - Y<sup>2</sup>/2E = 0</p><p><i>f</i> = &#963<sub>1</sub><sup>2</sup> + &#963<sub>2</sub><sup>2</sup> + &#963<sub>3</sub><sup>2</sup> - 2&#957(&#963<sub>1</sub>&#963<sub>2</sub> + &#963<sub>2</sub>&#963<sub>3</sub> + &#963<sub>3</sub>&#963<sub>1</sub>) - Y<sup>2</sup></p><p><i>f</i> = &#963<sub>e</sub><sup>2</sup> - Y<sup>2</sup></p><p>Thus, the effective stress is</p> <p>&#963<sub>e</sub> = &#963<sub>1</sub><sup>2</sup> + &#963<sub>2</sub><sup>2</sup> + &#963<sub>3</sub><sup>2</sup> - 2&#957(&#963<sub>1</sub>&#963<sub>2</sub> + &#963<sub>2</sub>&#963<sub>3</sub> + &#963<sub>3</sub>&#963<sub>1</sub>)</p><p>The yield surface for strain energy density criteria is ellipsoid in principal stress space, and depends on the Poission’s ratio &#957 for the material. When &#957 = 0, the ellipsoid is reduced to a sphere having a radius of yield stress Y.</p> ';
            app.Image3 = uiimage(app.StrainEnergyDensityCriterionTab);
            app.Image3.ImageSource = 'Capture-energy density.JPG';
            app.TrescaCriterionTab = uitab(app.TabGroup3);
            app.TrescaCriterionTab.Title = 'Tresca Criterion';
            app.HTML5 = uihtml(app.TrescaCriterionTab);
            app.HTML5.HTMLSource = '<br><p>Maximum shear stress criteria or Tresca Criteria states that yielding at a point in a material begins when the maximum shear stress is equal to the maximum shear stress at yield in uniaxial loading (tension or compression). The maximum shear stress in a multiaxial stress state is given by &#964<sub>max</sub> = (&#963<sub>max</sub> - &#963<sub>min</sub>)/2, where &#963<sub>max</sub> and &#963<sub>min</sub> are the maximum and minimum ordered principal stress components respectively. The maximum shear stress for uniaxial tension is &#964<sub>max</sub> = &#963/2. Yielding in uniaxial tension begins at yield stress Y. Thus, the shear stress associated with yielding is &#964<sub>max</sub> = Y/2, and the yield function for the maximum shear stress criteria is given by</p>  <p><i>f</i> = &#963<sub>e</sub> - Y/2</p><p>The effective stress is given as</p><p>&#963<sub>e</sub> = &#964<sub>max</sub> = (&#963<sub>max</sub> - &#963<sub>min</sub>)/2</p><p>For the given values of principal stresses &#963<sub>1</sub>, &#963<sub>2</sub>, &#963<sub>3</sub> in multiaxial loading, we have shear stress as</p><p>&#964<sub>1</sub> = |&#963<sub>1</sub> - &#963<sub>2</sub>|/2</p><p>&#964<sub>2</sub> = |&#963<sub>2</sub> - &#963<sub>3</sub>|/2</p><p>&#964<sub>3</sub> = |&#963<sub>3</sub> - &#963<sub>1</sub>|/2</p><p>The maximum shear stress &#964<sub>max</sub> is the largest of &#964<sub>1</sub>, &#964<sub>2</sub>, &#964<sub>3</sub>. For the given unordered principal stress, the yield surfaces are obtained using</p><p>&#963<sub>1</sub> - &#963<sub>2</sub> = &#177Y</p><p>&#963<sub>2</sub> - &#963<sub>3</sub> = &#177Y</p><p>&#963<sub>3</sub> - &#963<sub>1</sub> = &#177Y</p><p>The yield surface obtained for the Tresca criteria is a regular hexagon in principal stress space.</p> <p>The Tresca criteria match best with the experimental results for most ductile metals. Still, in a pure shear condition, like a torsion test, the shear yield stress is around 15% higher than that predicted by Tresca criteria for some ductile metals. Thus, the Tresca criterion is conservative for these metals. But being relatively accurate and computationally simple to use, the maximum shear stress criterion is a reasonable choice for most ductile metals.</p>';
            app.Image4 = uiimage(app.TrescaCriterionTab);
            app.Image4.ImageSource = 'Capture-tresca criteria.JPG';
            app.VonMisesCriterionTab = uitab(app.TabGroup3);
            app.VonMisesCriterionTab.Title = 'Von Mises Criterion';
            app.HTML6 = uihtml(app.VonMisesCriterionTab);
            app.HTML6.HTMLSource = '<br><p>The distortional energy density criteria, often referred to as von Mises criteria, states that yielding begins when distortional strain energy density at a point equals the distortional strain energy density at yield in uniaxial loading (tension or compression). The total strain energy density for multiaxial loading is given as</p><p>U<sub>o</sub> = (&#963<sub>1</sub> + &#963<sub>3</sub> + &#963<sub>3</sub>)<sup>2</sup>/18K + [(&#963<sub>1</sub> - &#963<sub>2</sub>)<sup>2</sup> + (&#963<sub>2</sub> - &#963<sub>3</sub>)<sup>2</sup> + (&#963<sub>3</sub> - &#963<sub>1</sub>)<sup>2</sup>]/12G</p><p>Where K is the bulk modulus (K = E/[3(1-2&#957)]) and G is the shear modulus (G = E/[2(1+&#957)]). The given strain energy density can be broken into two parts, one part is associated with the volumetric change U<sub>v</sub> and one that causes distortion U<sub>D</sub>.</p><p>U<sub>v</sub> = (&#963<sub>2</sub> + &#963<sub>2</sub> + &#963<sub>3</sub>)<sup>2</sup>/18K<p>U<sub>D</sub> = [(&#963<sub>1</sub> - &#963<sub>2</sub>)<sup>2</sup> + (&#963<sub>2</sub> - &#963<sub>3</sub>)<sup>2</sup> + (&#963<sub>3</sub> - &#963<sub>1</sub>)<sup>2</sup>]/12G</p><p>Under uniaxial loading at yield, U<sub>D</sub> = U<sub>D Y</sub> = Y<sup>2</sup>/6G. Thus, for a multiaxial stress state, yielding begins when distortional energy density U<sub>D</sub> at a point equals Y<sup>2</sup>/6G. The yield function for distortional energy density criteria is given as</p><p><i>f</i> = [(&#963<sub>1</sub> - &#963<sub>2</sub>)<sup>2</sup> + (&#963<sub>2</sub> - &#963<sub>3</sub>)<sup>2</sup> + (&#963<sub>3</sub> - &#963<sub>1</sub>)<sup>2</sup>]/12G - Y<sup>2</sup>/6G</p><p><i>f</i> = [&#963<sub>1</sub> - &#963<sub>2</sub>)<sup>2</sup> + (&#963<sub>2</sub> - &#963<sub>3</sub>)<sup>2</sup> + (&#963<sub>3</sub> - &#963<sub>1</sub>)<sup>2</sup>]/6- Y<sup>2</sup>/3</p><p><i>f</i>  = &#963<sub>e</sub><sup>2</sup> - Y<sup>2</sup><p>The effective stress &#963<sub>e</sub> is</p><p>&#963<sub>e</sub> = &radic;[(&#963<sub>1</sub><sup>2</sup> - &#963<sub>2</sub><sup>2</sup>) + (&#963<sub>2</sub><sup>2</sup> - &#963<sub>3</sub><sup>2</sup>) + (&#963<sub>3</sub><sup>2</sup> - &#963<sub>1</sub><sup>2</sup>)]/2</p><p>The effective stress in terms of the rectangular component of stress can be written as</p><p>&#963<sub>e</sub> = &#8730[(&#963<sub>xx</sub> - &#963<sub>yy</sub>)<sup>2</sup> + (&#963<sub>yy</sub> - &#963<sub>zz</sub>)<sup>2</sup> + (&#963<sub>zz</sub> - &#963<sub>xx</sub>)<sup>2</sup>]+3(&#963<sub>xy</sub><sup>2</sup> + &#963<sub>yz</sub><sup>2</sup> + &#963<sub>zx</sub><sup>2</sup>)</p><p>The distortional energy density criterion is also referred to as octahedral shear stress criterion and the yield function is given as</p><p><i>f</i> = &#964<sub>oct</sub> - &radic;<span style="text-decoration:overline">2</span>Y/3</p><p>The yield surface (<i>f</i> = 0) for a 3-dimensional stress state is a cylinder that circumscribes a Tresca hexagon. In a biaxial stress state, the von Mises yield surface reduces to an ellipse. The von Mises criteria is more accurate than the Tresca criteria in predicting yielding for some materials. Given a state of pure shear with the principal stresses &#963<sub>1</sub> = -&#963<sub>2</sub> = &#963, &#963<sub>3</sub> = 0. According to von Mises criteria yielding occurs when</p><p>3&#963<sup>2</sup> = Y<sup>2<sup/></p><p>&#963 = Y/&radic;<span style="text-decoration:overline">3</p><p>For the given principal stresses, we have maximum shear stress as &#964<sub>max</sub> = (&#963<sub>max</sub> - &#963<sub>min</sub>)/2 = &#963. Therefore, at yield  &#964<sub>max</sub> = &#964<sub>Y</sub> = Y/&radic;<span style="text-decoration:overline">3</span> = 0.577Y. Comparing it with the Tresca criteria, having shear stress at yield &#964<sub>Y</sub> = Y/2 = 0.5Y. Thus, the von Mises criteria predicts that pure shear yield stress is approximately 15% greater than Tresca criteria.</p>';
            app.Image5 = uiimage(app.VonMisesCriterionTab);
            app.Image5.ImageSource = 'Capture-von mises criterion.JPG';
            app.DataDictionaryTab = uitab(app.TabGroup2);
            app.DataDictionaryTab.Title = 'Data Dictionary';
            app.HTML7 = uihtml(app.DataDictionaryTab);
            app.HTML7.HTMLSource = '<br><p>The basic definition and understanding of terms used in the app as well as in the theory manual-</p><p><b>Bulk Modulus(K)</b> - It is the measure of ability of a substance to withstand the changes in volume when under compression on all sides. It is the ratio of volumetric stress to volumetric strain.</p><p><b>Dilatational Energy(U<sub>v</sub>)</b> - Strain energy associated with the change in volume of the body.</p> <p><b>Dilatational Energy Density(u<sub>v</sub>)</b> - Dilatational energy per unit volume of the body.</p><p><b>Distortional Energy(U<sub>D</sub>)</b> - Strain energy associated with the distortion of the body.</p> <p><b>Distortional Energy Density(u<sub>D</sub>)</b> - Distortional energy per unit volume of the body.</p> <p><b>Effective Stress(&#963<sub>e</sub>)</b> - Stress is the particular combination of the components of multiaxial stress state.</p><p><b>Elasticity</b> - It is the tendency of solid materials to return to their original shape after forces are applied to them.</p><p><b>Isotropic Material</b> - Materials that have identical properties in all directions.</p><p><b>Normal Strain(&#949<sub>xx</sub>, &#949<sub>yy</sub>, &#949<sub>zz</sub>)</b> - It is the change in length per unit length of the body due to the applied normal stress.</p><p><b>Normal Stress (&#963<sub>xx</sub>, &#963<sub>yy</sub>, &#963<sub>zz</sub>)</b> - It is the force applied per unit area which leads to increase or decrease (tensile and compressive respectively) in length of the body.</p><p><b>Octahedral Shear Stress(&#964<sub>oct</sub>)</b> - The shear stress corresponding to the octahedral planes in a stressed body. </p><p><b>Plasticity</b> - It is the ability of solid material to undergo permanent deformation in response to applied forces.</p><p><b>Poisson’s Ratio(&#957)</b> - It is defined as the ratio of change in the width per unit width of material, to change in its length per unit length, as a result of strain.</p><p><b>Principal Strain(&#949<sub>1</sub>, &#949<sub>2</sub>, &#949<sub>3</sub>)</b> - The normal strains along the plane having the shear strain zero. </p><p><b>Principal Stress (&#963<sub>1</sub>, &#963<sub>2</sub>, &#963<sub>3</sub>)</b> - It corresponds to the normal stress at an angle at which the shear stress is zero. </p><p><b>Safety Factor(SF)</b> - It is the ratio of the material&#8242s yield strength to the actual load (effective stress).</p><p><b>Shear Modulus(G)</b> - It is the Earth&#8242s material response to shear stress. It is defined as the ratio of shear stress to shear strain.</p><p><b>Shear Strain(&#949<sub>xy</sub>, &#949<sub>yz</sub>, &#949<sub>zx</sub>)</b> - It is the ratio of the change in deformation to its original length perpendicular to the axes of the member due to shear stress.</p><p><b>Shear Stress(&#964, &#963<sub>xy</sub>, &#963<sub>yz</sub>, &#963<sub>zx</sub>)</b> - It is the force per unit area tending to cause deformation of a material by slippage along a plane or a plane parallel to the imposed stress. </p><p><b>Strain Energy (U<sub>o</sub>)</b> - It is defined as the energy stored in a body due to deformation.</p><p><b>Strain Energy Density(u)</b> - Strain energy per unit volume of a material.</p><p><b>Yield Function(<i>f</i>)</b> - A mathematical form that relates effective stress and the yield stress.</p><p><b>Yield Strain(&#949<sub>Y</sub>)</b> - It is the strain associated with yield stress.</p><p><b>Yield Stress(&#963<sub>YS</sub> or Y)</b> - It is the stress needed to be applied to an object to cause it to change from elastic deformation to plastic deformation. </p><p><b>Yield Surface</b> - The locus of stress states for which the yield criterion is satisfied (<i>f</i> = 0).</p><p><b>Young’s Modulus(E)</b> - It is the measure of the ability of a material to withstand the change in length when under lengthwise tension or compression. It is the ratio of normal stress to normal strain.</p>';
            app.TestCaseManualTab = uitab(app.TabGroup2);
            app.TestCaseManualTab.Title = 'Test Case Manual';
            app.HTML10 = uihtml(app.TestCaseManualTab);
            app.HTML10.HTMLSource = '<p><strong>&nbsp;</strong></p><p><span style="font-weight: 400;">We had two different input options one is 3 principal stresses(𝜎<sub>1</sub></span><span style="font-weight: 400;">,𝜎2</span><span style="font-weight: 400;">,𝜎3</span><span style="font-weight: 400;">) and the other is 6 rectangular component stresses(𝜎<sub>xx</sub></span><span style="font-weight: 400;">,𝜎<sub>yy</sub></span><span style="font-weight: 400;">,𝜎<sub>zz</sub></span><span style="font-weight: 400;">,𝜎<sub>xy</sub></span><span style="font-weight: 400;">,𝜎<sub>yz</sub></span><span style="font-weight: 400;">, 𝜎<sub>zx</sub></span><span style="font-weight: 400;">). Both of them have a common input section for yield strength (Y) and Poisson''s ratio(</span><span style="font-weight: 400;">𝜈</span><span style="font-weight: 400;">).&nbsp;</span></p><p><em><span style="font-weight: 400;">Fail case-1</span></em><span style="font-weight: 400;">: The app gives the results if the user gives either 3 principle stresses or 6 rectangular component stresses but not both at once for a given yield strength and poisons ratio.</span></p><p><span style="font-weight: 400;">(</span><strong>Resolved</strong><span style="font-weight: 400;">)</span><span style="font-weight: 400;">: If the user tries to enter values in both the principal value and rectangular components segments he/she will get an error window on the screen.</span></p><p><em><span style="font-weight: 400;">Fail case-2</span></em><span style="font-weight: 400;">: Earlier the Poisson''s ratio was not restricted from 0 to1. Users can be able to enter poisons ratio values as they wish to, which leads to incorrect results.</span></p><p><span style="font-weight: 400;">(</span><strong>Resolved</strong><span style="font-weight: 400;">)</span><span style="font-weight: 400;">: If the user gives a poisons ratio number which is not in the limit of 0 to 1. Then the input value goes to zero and a small message appears as &lsquo;you need to enter a value between 0 to 1.&rsquo;</span></p><p><span style="font-weight: 400;">Users can write the components or the principal stresses to obtain the effective stresses corresponding to different yield criteria. So it is flexible for the user.</span></p><p><span style="font-weight: 400;">If users want to find effective stresses and safety factors in-plane stress cases, then also he/she can get the results. So, it is applicable to 1D, 2D, and 3D stress cases problems.</span></p><p><strong><em>There are no-fail cases as of now.</em></strong></p><p>&nbsp;</p><p><span style="font-weight: 400;">THEORETICAL SOLUTION OF APP RESULTS</span><span style="font-weight: 400;">:</span></p><p><em><span style="font-weight: 400;">Example-1</span></em><span style="font-weight: 400;">: With principle stresses as inputs.</span></p><p><span style="font-weight: 400;">&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;Given,</span></p><p><span style="font-weight: 400;">Yeild strength (Y) = 600 MPa</span></p><p><span style="font-weight: 400;">Poinsons ratio(</span><span style="font-weight: 400;">𝜈</span><span style="font-weight: 400;">) = 0.5</span></p><p><span style="font-weight: 400;">Principle stresses,</span></p><p><span style="font-weight: 400;">𝜎<sub>1&nbsp;</sub></span><span style="font-weight: 400;">= 400 MPa</span></p><p><span style="font-weight: 400;">𝜎2</span><span style="font-weight: 400;">&nbsp;= 50 MPa</span></p><p><span style="font-weight: 400;">𝜎3</span><span style="font-weight: 400;">&nbsp;= 75 MPa</span></p><p>&nbsp;</p><p><span style="font-weight: 400;">1)Maximum Principal Stress criterion</span></p><p><span style="font-weight: 400;">𝑓 = 𝑚𝑎𝑥(|𝜎1</span><span style="font-weight: 400;">&nbsp;|, |𝜎2</span><span style="font-weight: 400;">&nbsp;|, |𝜎3</span><span style="font-weight: 400;">|) &minus; Y</span></p><p><span style="font-weight: 400;">&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;Effective stress</span></p><p><span style="font-weight: 400;">&nbsp;𝜎e</span><span style="font-weight: 400;">&nbsp;= 𝑚𝑎𝑥(|𝜎1<span style="font-weight: 400;">&nbsp;|, |𝜎2</span><span style="font-weight: 400;">&nbsp;|, |𝜎3</span><span style="font-weight: 400;">|</span></span><span style="font-weight: 400;">)</span></p><p><span style="font-weight: 400;"> 𝜎e</span><span style="font-weight: 400;">&nbsp;= 𝑚𝑎𝑥(|400|, |50|, |75|)</span></p><p><strong>𝜎e</strong><strong>&nbsp;= </strong><strong>400MPa</strong></p><p><span style="font-weight: 400;">&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;Safety factor</span></p><p><span style="font-weight: 400;">SF = Y/𝜎e</span><span style="font-weight: 400;">&nbsp;</span></p><p><span style="font-weight: 400;">SF = 600/400</span></p><p><strong>SF = </strong><strong>1.5</strong></p><p>&nbsp;</p><p><span style="font-weight: 400;">2)Maximum Principal Strain Criterion</span></p><p><span style="font-weight: 400;">𝑓 = 𝑚𝑎𝑥(|𝜀1</span><span style="font-weight: 400;">&nbsp;|, |𝜀2</span><span style="font-weight: 400;">&nbsp;|, |𝜀3</span><span style="font-weight: 400;">&nbsp;|) &minus; 𝜀<sub>Y</sub></span></p><p><span style="font-weight: 400;">&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;Effective stress</span></p><p><span style="font-weight: 400;">𝜎e</span><span style="font-weight: 400;">&nbsp;= 𝑚𝑎𝑥|𝜎i</span><span style="font-weight: 400;">&nbsp;&minus; 𝜈𝜎j</span><span style="font-weight: 400;">&nbsp;&minus; 𝜈𝜎<sub>k</sub></span><span style="font-weight: 400;">|&nbsp; (𝑖 &ne; 𝑗 &ne; 𝑘)</span></p><p><span style="font-weight: 400;">&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;We know that 𝜎1</span><span style="font-weight: 400;">&nbsp;max of all principal stresses.</span></p><p><span style="font-weight: 400;">𝜎e</span><span style="font-weight: 400;">&nbsp;= |400 &minus; (0.5)50&minus; (0.5)75|</span></p><p><strong>&nbsp;𝜎e</strong><strong>= </strong><strong>337.5 MPa</strong></p><p><span style="font-weight: 400;">&nbsp;Safety factor</span></p><p><span style="font-weight: 400;">SF = Y/𝜎e</span><span style="font-weight: 400;">&nbsp;</span></p><p><span style="font-weight: 400;">SF = 600/337.5</span></p><p><strong>SF = </strong><strong>1.77778</strong><span style="font-weight: 400;">&nbsp;</span></p><p>&nbsp;</p><p><span style="font-weight: 400;">3)Strain Energy Density Criterion</span></p><p><span style="font-weight: 400;">𝑓 = 𝜎2e</span><span style="font-weight: 400;">&minus; </span><span style="font-weight: 400;">Y2</span></p><p><span style="font-weight: 400;">&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;Effective stress</span></p><p><span style="font-weight: 400;">𝜎<sub>e</sub></span><span style="font-weight: 400;">&nbsp;= (𝜎2<sub>1</sub></span><span style="font-weight: 400;">+ 𝜎2</span><span style="font-weight: 400;">2+ 𝜎<sup>2</sup>3</span><span style="font-weight: 400;">&nbsp;&minus; 2𝜈(𝜎1𝜎2</span><span style="font-weight: 400;">+ 𝜎2𝜎3</span><span style="font-weight: 400;">&nbsp;+𝜎3𝜎1</span><span style="font-weight: 400;">)<sup>0.5</sup></span></p><p><strong>𝜎<sub>e</sub></strong><strong>&nbsp;= </strong><strong>338.193731 MPa</strong></p><p><span style="font-weight: 400;">&nbsp;Safety factor</span></p><p><span style="font-weight: 400;">SF = Y/𝜎<sub>e</sub></span><span style="font-weight: 400;">&nbsp;</span></p><p><span style="font-weight: 400;">SF = 600/338.193731</span></p><p><strong>SF = </strong><strong>1.77413</strong></p><p>&nbsp;</p><p><span style="font-weight: 400;">4)Maximum Shear Stress Criterion or Tresca Criterion</span></p><p><span style="font-weight: 400;">𝑓 = 𝜎e</span><span style="font-weight: 400;">&nbsp;&minus; 𝑌/2</span></p><p><span style="font-weight: 400;">&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;Effective stress</span></p><p><span style="font-weight: 400;">𝜎e</span><span style="font-weight: 400;">&nbsp;= 𝜏<sub>max</sub></span><span style="font-weight: 400;">&nbsp;= (𝜎<sub>max</sub></span><span style="font-weight: 400;">&nbsp;&minus; 𝜎<sub>min</sub></span><span style="font-weight: 400;">)/2&nbsp;</span></p><p><span style="font-weight: 400;">𝜎<sub>e</sub></span><span style="font-weight: 400;">&nbsp;=&nbsp; (400 - 50)/2</span></p><p><span style="font-weight: 400;">&nbsp;</span><strong>𝜎<sub>e</sub></strong><strong>&nbsp;= </strong><strong>175 MPa</strong></p><p><span style="font-weight: 400;">&nbsp;Safety factor</span></p><p><span style="font-weight: 400;">SF = Y/𝜎<sub>e</sub></span><span style="font-weight: 400;">&nbsp;</span></p><p><span style="font-weight: 400;">SF = 600/175</span></p><p><strong>SF = </strong><strong>3.42857</strong></p><p>&nbsp;</p><p><span style="font-weight: 400;">5)Distortion Energy Density Criterion or Von Mises Criterion</span></p><p><span style="font-weight: 400;">𝑓 = 𝜎2<sub>e</sub></span><span style="font-weight: 400;">&minus; </span><span style="font-weight: 400;">Y<sup>2</sup></span></p><p><span style="font-weight: 400;">&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;Effective stress</span></p><p><span style="font-weight: 400;">𝜎<sub>e</sub></span><span style="font-weight: 400;">&nbsp;= (</span><span style="font-weight: 400;">1/2 [(𝜎1</span><span style="font-weight: 400;">&nbsp;&minus; 𝜎2</span><span style="font-weight: 400;">)2</span><span style="font-weight: 400;">&nbsp;+ (𝜎2</span><span style="font-weight: 400;">&nbsp;&minus; 𝜎3</span><span style="font-weight: 400;">)2</span><span style="font-weight: 400;">&nbsp;+&nbsp; (</span><span style="font-weight: 400;">&nbsp;𝜎3&minus; 𝜎1</span><span style="font-weight: 400;">)2</span><span style="font-weight: 400;">&nbsp;])<sup>0.5</sup></span></p><p><strong>𝜎</strong><strong>e</strong><strong> = </strong><strong>338.193731 MPa</strong></p><p><span style="font-weight: 400;">&nbsp;Safety factor</span></p><p><span style="font-weight: 400;">SF = Y/𝜎e</span><span style="font-weight: 400;">&nbsp;</span></p><p><span style="font-weight: 400;">SF = 600/338.193731</span></p><p><strong>SF = 1</strong><strong>.77413</strong></p><p><br /><br /></p><p><em><span style="font-weight: 400;">Example-2</span></em><span style="font-weight: 400;">: With rectangular component stresses as inputs.</span></p><p><span style="font-weight: 400;">&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;Given,</span></p><p><span style="font-weight: 400;">Yeild strength (Y) = 600 MPa</span></p><p><span style="font-weight: 400;">Poinsons ratio(</span><span style="font-weight: 400;">𝜈</span><span style="font-weight: 400;">) = 0.5</span></p><p><span style="font-weight: 400;">Principle stresses,</span></p><p><span style="font-weight: 400;">𝜎<sub>xx</sub></span><span style="font-weight: 400;">&nbsp;= 20 MPa</span></p><p><span style="font-weight: 400;">𝜎<sub>yy</sub></span><span style="font-weight: 400;">&nbsp;= 460 MPa</span></p><p><span style="font-weight: 400;">𝜎<sub>zz</sub></span><span style="font-weight: 400;">&nbsp;= 10 MPa</span></p><p><span style="font-weight: 400;">𝜎<sub>xy</sub></span><span style="font-weight: 400;">&nbsp;= 135 MPa</span></p><p><span style="font-weight: 400;">𝜎<sub>yz</sub></span><span style="font-weight: 400;">&nbsp;= 290 MPa</span></p><p><span style="font-weight: 400;">𝜎<sub>zx</sub></span><span style="font-weight: 400;">&nbsp;= 140 MPa</span></p><p><span style="font-weight: 400;">Stress tensor,</span></p><p><span style="font-weight: 400;">&nbsp; &nbsp; &nbsp; &nbsp; &nbsp; &nbsp;20&nbsp; &nbsp; 135&nbsp; &nbsp; 140</span></p><p><span style="font-weight: 400;">T = &nbsp; &nbsp; 135&nbsp; &nbsp; 460&nbsp; &nbsp; 290</span></p><p><span style="font-weight: 400;"> &nbsp; &nbsp; &nbsp; &nbsp; &nbsp; 140 &nbsp; 290 &nbsp; &nbsp; 10</span></p><p><span style="font-weight: 400;">By solving equation</span>&nbsp;|<span style="font-weight: 400;">T - &lambda;</span><span style="font-weight: 400;">I</span><span style="font-weight: 400;">&nbsp;|= 0</span></p><p><span style="font-weight: 400;">We get,</span></p><p><span style="font-weight: 400;">&lambda;<sup>3</sup></span><span style="font-weight: 400;">&nbsp;- 490&lambda;2&nbsp;</span><span style="font-weight: 400;">-&nbsp; 107925&lambda;</span><span style="font-weight: 400;"> - 173705 = 0</span></p><p><span style="font-weight: 400;">By solving the above equation we get three different values of </span><span style="font-weight: 400;">(-163.52, -1.6219, 655.14) which are called principle stresses (𝜎1</span><span style="font-weight: 400;">&nbsp;</span><span style="font-weight: 400;">,𝜎2</span><span style="font-weight: 400;">&nbsp;</span><span style="font-weight: 400;">,𝜎3</span><span style="font-weight: 400;">) corresponding to rectangular component stresses(𝜎<sub>xx</sub></span><span style="font-weight: 400;">&nbsp;,𝜎<sub>yy</sub></span><span style="font-weight: 400;">&nbsp;,𝜎<sub>zz</sub></span><span style="font-weight: 400;">&nbsp;,𝜎<sub>xy</sub></span><span style="font-weight: 400;">&nbsp;,𝜎<sub>yz</sub></span><span style="font-weight: 400;">&nbsp;,𝜎<sub>zx</sub></span><span style="font-weight: 400;">).</span></p><p><span style="font-weight: 400;">We get,</span></p><p><span style="font-weight: 400;">𝜎<sub>1</sub></span><span style="font-weight: 400;">&nbsp;= -163.52 MPa</span></p><p><span style="font-weight: 400;">𝜎<sub>2</sub></span><span style="font-weight: 400;">&nbsp;= -1.6219 MPa</span></p><p><span style="font-weight: 400;">𝜎3</span><span style="font-weight: 400;">&nbsp;= 655.14 MPa</span></p><p>&nbsp;</p><p><span style="font-weight: 400;">1)Maximum Principal Stress criterion</span></p><p><span style="font-weight: 400;">𝑓 = 𝑚𝑎𝑥(|𝜎1</span><span style="font-weight: 400;">&nbsp;|, |𝜎2</span><span style="font-weight: 400;">&nbsp;|, |𝜎3</span><span style="font-weight: 400;">&nbsp;|) &minus; Y</span></p><p><span style="font-weight: 400;">&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;Effective stress</span></p><p><span style="font-weight: 400;">&nbsp;𝜎e</span><span style="font-weight: 400;">&nbsp;= 𝑚𝑎𝑥(|𝜎1</span><span style="font-weight: 400;">&nbsp;|, |𝜎2</span><span style="font-weight: 400;">&nbsp;|, |𝜎3</span><span style="font-weight: 400;">&nbsp;|)</span></p><p><span style="font-weight: 400;"> 𝜎e</span><span style="font-weight: 400;">&nbsp;= 𝑚𝑎𝑥(|-163.52|, |-1.6219|, |655.14|)</span></p><p><strong>𝜎e</strong><strong>&nbsp;= </strong><strong>655.14 MPa</strong></p><p><span style="font-weight: 400;">&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;Safety factor</span></p><p><span style="font-weight: 400;">SF = Y/𝜎e</span><span style="font-weight: 400;">&nbsp;</span></p><p><span style="font-weight: 400;">SF = 600/655.14</span></p><p><strong>SF = </strong><strong>0.915834</strong></p><p>&nbsp;</p><p><span style="font-weight: 400;">2)Maximum Principal Strain Criterion</span></p><p><span style="font-weight: 400;">𝑓 = 𝑚𝑎𝑥(|𝜀1</span><span style="font-weight: 400;">&nbsp;|, |𝜀2</span><span style="font-weight: 400;">&nbsp;|, |𝜀3</span><span style="font-weight: 400;">&nbsp;|) &minus; 𝜀<sub>Y</sub></span></p><p><span style="font-weight: 400;">&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;Effective stress</span></p><p><span style="font-weight: 400;">𝜎e&nbsp;</span><span style="font-weight: 400;">= 𝑚𝑎𝑥|𝜎i</span><span style="font-weight: 400;">&nbsp;&minus; 𝜈𝜎j</span><span style="font-weight: 400;">&nbsp;&minus; 𝜈𝜎<sub>k</sub></span><span style="font-weight: 400;">|&nbsp; (𝑖 &ne; 𝑗 &ne; 𝑘)</span></p><p><span style="font-weight: 400;">&nbsp;&nbsp;We know that 𝜎<sub>3</sub></span><span style="font-weight: 400;">&nbsp;is max of all principal stresses.</span></p><p><span style="font-weight: 400;">𝜎<sub>e</sub></span><span style="font-weight: 400;">&nbsp;= |655.14 &minus; (0.5)(-163.52)&minus; (0.5)(-1.6219)|</span></p><p><strong>&nbsp;𝜎<sub>e</sub></strong><strong>= </strong><strong>737.711 MPa</strong></p><p><span style="font-weight: 400;">&nbsp;Safety factor</span></p><p><span style="font-weight: 400;">SF = Y/𝜎<sub>e</sub></span><span style="font-weight: 400;">&nbsp;</span></p><p><span style="font-weight: 400;">SF = 600/737.711</span></p><p><strong>SF = </strong><strong>0.813327</strong></p><p>&nbsp;</p><p><span style="font-weight: 400;">3)Strain Energy Density Criterion</span></p><p><span style="font-weight: 400;">𝑓 =&nbsp;𝜎2<sub>e</sub><span style="font-weight: 400;">&minus;&nbsp;</span><span style="font-weight: 400;">Y<sup>2</sup></span></span></p><p><span style="font-weight: 400;">&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;Effective stress</span></p><p><span style="font-weight: 400;">𝜎e</span><span style="font-weight: 400;">&nbsp;=&nbsp;(𝜎2<sub>1</sub><span style="font-weight: 400;">+ 𝜎22</span><span style="font-weight: 400;">+ 𝜎<sup>2</sup>3</span><span style="font-weight: 400;">&nbsp;&minus; 2𝜈(𝜎1𝜎2</span><span style="font-weight: 400;">+&nbsp;𝜎2𝜎3</span><span style="font-weight: 400;">&nbsp;+𝜎3𝜎1</span><span style="font-weight: 400;">)<sup>0.5</sup></span></span></p><p><strong>𝜎e</strong><strong>&nbsp;= </strong><strong>750.916 MPa</strong></p><p><span style="font-weight: 400;">&nbsp;Safety factor</span></p><p><span style="font-weight: 400;">SF = Y/𝜎e</span><span style="font-weight: 400;">&nbsp;</span></p><p><span style="font-weight: 400;">SF = 600/750.916</span></p><p><strong>SF = </strong><strong>0.799024</strong></p><p>&nbsp;</p><p><span style="font-weight: 400;">4)Maximum Shear Stress Criterion or Tresca Criterion</span></p><p><span style="font-weight: 400;">𝑓 = 𝜎e</span><span style="font-weight: 400;">&nbsp;&minus; 𝑌/2</span></p><p><span style="font-weight: 400;">&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;Effective stress</span></p><p><span style="font-weight: 400;">𝜎e</span><span style="font-weight: 400;">&nbsp;= 𝜏<sub>max&nbsp;</sub></span><span style="font-weight: 400;">= (𝜎<sub>max</sub></span><span style="font-weight: 400;">&nbsp;&minus; 𝜎<sub>min</sub></span><span style="font-weight: 400;">)/2&nbsp;</span></p><p><span style="font-weight: 400;">𝜎<sub>e</sub></span><span style="font-weight: 400;">&nbsp;=&nbsp; [(655.14 - (-163.52)]/2</span></p><p><span style="font-weight: 400;">&nbsp;</span><strong>𝜎e</strong><strong>&nbsp;= </strong><strong>409.33 MPa</strong></p><p><span style="font-weight: 400;">&nbsp;Safety factor</span></p><p><span style="font-weight: 400;">SF = Y/𝜎<sub>e</sub></span><span style="font-weight: 400;">&nbsp;</span></p><p><span style="font-weight: 400;">SF = 600/409.33</span></p><p><strong>SF = </strong><strong>1.46581</strong></p><p>&nbsp;</p><p><span style="font-weight: 400;">5)Distortion Energy Density Criterion or Von Mises Criterion</span></p><p><span style="font-weight: 400;">𝑓 =&nbsp;𝜎2<sub>e</sub><span style="font-weight: 400;">&minus;&nbsp;</span><span style="font-weight: 400;">Y<sup>2</sup></span></span></p><p><span style="font-weight: 400;">&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;Effective stress</span></p><p><span style="font-weight: 400;">𝜎e</span><span style="font-weight: 400;">&nbsp;=&nbsp;&nbsp;(<span style="font-weight: 400;">1/2 [(𝜎1</span><span style="font-weight: 400;">&nbsp;&minus;&nbsp;𝜎2</span><span style="font-weight: 400;">)2</span><span style="font-weight: 400;">&nbsp;+ (𝜎2</span><span style="font-weight: 400;">&nbsp;&minus;&nbsp;𝜎3</span><span style="font-weight: 400;">)2</span><span style="font-weight: 400;">&nbsp;+&nbsp; (</span><span style="font-weight: 400;">&nbsp;𝜎3&minus;&nbsp;𝜎1</span><span style="font-weight: 400;">)<sup>2</sup></span><span style="font-weight: 400;">&nbsp;])<sup>0.5</sup></span></span></p><p><strong>𝜎e</strong><strong>&nbsp;= </strong><strong>750.916 MPa</strong></p><p><span style="font-weight: 400;">&nbsp;Safety factor</span></p><p><span style="font-weight: 400;">SF = Y/𝜎e</span><span style="font-weight: 400;">&nbsp;</span></p><p><span style="font-weight: 400;">SF = 600/338.193731</span></p><p><strong>SF = 0.799024</strong></p><p><br /><br /></p>';
            % Show the figure after all components are created
            app.YieldCriteriaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MODB_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.YieldCriteriaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.YieldCriteriaUIFigure)
        end
    end
end